classdef appProt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        SelectroutetoexportfileButton  matlab.ui.control.Button
        ColorEditField                 matlab.ui.control.NumericEditField
        ColorEditFieldLabel            matlab.ui.control.Label
        ORPEditField                   matlab.ui.control.NumericEditField
        ORPEditFieldLabel              matlab.ui.control.Label
        DOEditField                    matlab.ui.control.NumericEditField
        DOEditFieldLabel               matlab.ui.control.Label
        SalinityEditField              matlab.ui.control.NumericEditField
        SalinityEditFieldLabel         matlab.ui.control.Label
        TSSEditField                   matlab.ui.control.NumericEditField
        TSSEditFieldLabel              matlab.ui.control.Label
        ConductivityEditField          matlab.ui.control.NumericEditField
        ConductivityEditFieldLabel     matlab.ui.control.Label
        pHEditField                    matlab.ui.control.NumericEditField
        pHEditFieldLabel               matlab.ui.control.Label
        TemperatureEditField           matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel      matlab.ui.control.Label
        PeriodEditField                matlab.ui.control.NumericEditField
        PeriodEditFieldLabel           matlab.ui.control.Label
        SerialPortDropDown             matlab.ui.control.DropDown
        SerialPortDropDownLabel        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxesColor                    matlab.ui.control.UIAxes
        UIAxesORP                      matlab.ui.control.UIAxes
        UIAxesSalinity                 matlab.ui.control.UIAxes
        UIAxesDO                       matlab.ui.control.UIAxes
        UIAxesTSS                      matlab.ui.control.UIAxes
        UIAxesConductivity             matlab.ui.control.UIAxes
        UIAxespH                       matlab.ui.control.UIAxes
        UIAxesTemperature              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serialPort % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialPortDropDown.Items=serialportlist;
            val=app.SerialPortDropDown.Value;
            app.serialPort=serialport(val,9600,"Timeout",10);
        end

        % Button pushed function: SelectroutetoexportfileButton
        function SelectroutetoexportfileButtonPushed(app, event)
            global filename path file
            
            [file,path] = uiputfile('*.csv');
            filename = fullfile(path,file);
            
            if isequal(file,0) || isequal(path,0)
                uialert(app.UIFigure,'User clicked Cancel.','Info','Icon','info');
                drawnow;
                figure(app.UIFigure);
            else
                uialert(app.UIFigure,['User selected ',fullfile(path,file),...
                ' and then clicked Save.'],'Success','Icon','success');
                drawnow;
                figure(app.UIFigure);
            end
            

            %writematrix(dataR,'register.csv','WriteMode',"append");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global filename path file dataR Temperature pH Conductivity TSS DO Salinity ORP Color Date_R Time_R
            disp(file)
            disp(path)
            
            if isempty(file)==0 || isempty(path)==0
                path=fileparts('filename');
                file='tableData.csv';
                filename = fullfile(path,file);
                disp('true 1')
            else
                cd(path);
                disp('true 2')
                disp(file)
                disp(path)
            end
                disp(file)
                disp(path)
                disp('false')
            dataR=table(Temperature,pH,Conductivity,TSS,DO,Salinity,ORP,Color,Date_R,Time_R);
            writetable(dataR,file,"WriteMode","append");
            
            
            %app.serialPort
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesTemperature = uiaxes(app.UIFigure);
            title(app.UIAxesTemperature, 'Temperature')
            xlabel(app.UIAxesTemperature, 'X')
            ylabel(app.UIAxesTemperature, 'Y')
            zlabel(app.UIAxesTemperature, 'Z')
            app.UIAxespH = uiaxes(app.UIFigure);
            title(app.UIAxespH, 'pH')
            xlabel(app.UIAxespH, 'X')
            ylabel(app.UIAxespH, 'Y')
            zlabel(app.UIAxespH, 'Z')
            app.UIAxesConductivity = uiaxes(app.UIFigure);
            title(app.UIAxesConductivity, 'Conductivity')
            xlabel(app.UIAxesConductivity, 'X')
            ylabel(app.UIAxesConductivity, 'Y')
            zlabel(app.UIAxesConductivity, 'Z')
            app.UIAxesTSS = uiaxes(app.UIFigure);
            title(app.UIAxesTSS, 'Total Suspended Solids')
            xlabel(app.UIAxesTSS, 'X')
            ylabel(app.UIAxesTSS, 'Y')
            zlabel(app.UIAxesTSS, 'Z')
            app.UIAxesDO = uiaxes(app.UIFigure);
            title(app.UIAxesDO, 'Dissolved Oxygen')
            xlabel(app.UIAxesDO, 'X')
            ylabel(app.UIAxesDO, 'Y')
            zlabel(app.UIAxesDO, 'Z')
            app.UIAxesSalinity = uiaxes(app.UIFigure);
            title(app.UIAxesSalinity, 'Salinity')
            xlabel(app.UIAxesSalinity, 'X')
            ylabel(app.UIAxesSalinity, 'Y')
            zlabel(app.UIAxesSalinity, 'Z')
            app.UIAxesORP = uiaxes(app.UIFigure);
            title(app.UIAxesORP, 'Oxidation Reduction Potential')
            xlabel(app.UIAxesORP, 'X')
            ylabel(app.UIAxesORP, 'Y')
            zlabel(app.UIAxesORP, 'Z')
            app.UIAxesColor = uiaxes(app.UIFigure);
            title(app.UIAxesColor, 'Color')
            xlabel(app.UIAxesColor, 'X')
            ylabel(app.UIAxesColor, 'Y')
            zlabel(app.UIAxesColor, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'UDG.png';
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.PeriodEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField.Editable = 'off';
            app.pHEditFieldLabel = uilabel(app.UIFigure);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHEditField = uieditfield(app.UIFigure, 'numeric');
            app.pHEditField.Editable = 'off';
            app.ConductivityEditFieldLabel = uilabel(app.UIFigure);
            app.ConductivityEditFieldLabel.Text = 'Conductivity';
            app.ConductivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConductivityEditField.Editable = 'off';
            app.TSSEditFieldLabel = uilabel(app.UIFigure);
            app.TSSEditFieldLabel.Text = 'TSS';
            app.TSSEditField = uieditfield(app.UIFigure, 'numeric');
            app.TSSEditField.Editable = 'off';
            app.SalinityEditFieldLabel = uilabel(app.UIFigure);
            app.SalinityEditFieldLabel.Text = 'Salinity';
            app.SalinityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SalinityEditField.Editable = 'off';
            app.DOEditFieldLabel = uilabel(app.UIFigure);
            app.DOEditFieldLabel.Text = 'DO';
            app.DOEditField = uieditfield(app.UIFigure, 'numeric');
            app.DOEditField.Editable = 'off';
            app.ORPEditFieldLabel = uilabel(app.UIFigure);
            app.ORPEditFieldLabel.Text = 'ORP';
            app.ORPEditField = uieditfield(app.UIFigure, 'numeric');
            app.ORPEditField.Editable = 'off';
            app.ColorEditFieldLabel = uilabel(app.UIFigure);
            app.ColorEditFieldLabel.Text = 'Color';
            app.ColorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorEditField.Editable = 'off';
            app.SelectroutetoexportfileButton = uibutton(app.UIFigure, 'push');
            app.SelectroutetoexportfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectroutetoexportfileButtonPushed, true);
            app.SelectroutetoexportfileButton.Text = 'Select route to export file';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appProt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end